classdef UI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        CalibrationStatusTextArea  matlab.ui.control.TextArea
        CalibrationStatusLabel     matlab.ui.control.Label
        dataBottom                 matlab.ui.control.DropDown
        DisplayedDataLabel         matlab.ui.control.Label
        OpenFileButton             matlab.ui.control.Button
        PausePlay                  matlab.ui.control.StateButton
        ModelPlot                  matlab.ui.control.UIAxes
        BoxPlot                    matlab.ui.control.UIAxes
        bottomPlot                 matlab.ui.control.UIAxes
        topPlot                    matlab.ui.control.UIAxes
    end


    properties (Access = private)
        oldpath = addpath('..\Integrated system','-begin')
        % app parameters
        time
        %tDelta
        tIndex
        fs
        fps
        StartStop
        n 
        label 
        
        % plot handles
        h1_1                  
        h1_2                  
        h2_1                 
        h2_2
        h3_1
        h3_2
        h4_1               
        h4_2
        h4_3                 
        h4_4                  
            
        % paramters related to system calibration status 
        calib_status 
        ind_finish_calib
        ind_after_calib
       
        
        % flexion/extension of hip joints
        flexEx_LH            
        flexEx_RH            
        
        % parameters used to update bar plot
        act_label
        sprint_time 
        prev_act 
        cur_act 
        
        % load
        load_LH
        load_RH
        
        % body segments orientations
        q_pelvis 
        q_leftthigh 
        q_rightthigh
        
        P
        PV
        LT
        RT
        
        pel_Rot;  
        leftthigh_Rot;
        rightthigh_Rot;

    end

    methods (Access = private)
    
        function [] = plotApp(app)
           
            while app.StartStop == 1 && app.tIndex<=length(app.time)-100
                
                tic;
                updateModel(app)
                drawnow limitrate
                
                % update time and time index
                if app.tIndex +app.n<length(app.time)
                    app.tIndex = app.tIndex +app.n;
                else
                    app.tIndex = length(app.time);
                end
                
                tspent = toc;
                
                if tspent<(1/app.fps)
                    pause((1/app.fps)-tspent)
                end   

            end
        end
        
        function [] = updateModel(app)
            
            % moving average
            m = app.tIndex:app.tIndex+app.n-1; %1:25, 26:50,..... 4326:4350
                      
            if app.tIndex == app.ind_finish_calib
                app.calib_status = 1;
                app.CalibrationStatusTextArea.Value = {'Done'};
            end
            
            if app.calib_status == 1  
                app.ind_after_calib = app.ind_after_calib + 1;
                % update bar_plot 
                if mod(app.ind_after_calib-1,4) == 0
                    
                        app.cur_act = app.act_label(app.tIndex); 
                  
                        if app.cur_act <2
                               app.h1_1.YData(app.cur_act+1) =  app.h1_1.YData(app.cur_act+1)+0.2;
                        elseif app.cur_act == 4
                                app.h1_1.YData(3) =  app.h1_1.YData(3)+0.2;
                        elseif app.cur_act ~= app.prev_act
                                app.h1_2.YData(app.cur_act+2) =  app.h1_2.YData(app.cur_act+2)+1;
                        end
                            
                        if app.prev_act~=4 && app.cur_act==4
                            t_start = (app.tIndex-1)/app.fs;
                            xline(app.bottomPlot,t_start,'r:','Start','FontSize',6,'LabelHorizontalAlignment','center');
                        end
                        if app.prev_act==4 && app.cur_act~=4
                            t_end = (app.tIndex-1)/app.fs;
                            xline(app.bottomPlot,t_end,'r:','End','FontSize',6,'LabelHorizontalAlignment','center');
                        end
                            app.prev_act = app.cur_act;
                end
                
                % update line graph
                x = m/app.fs;
                addpoints(app.h2_1,x,app.load_LH(m));
                addpoints(app.h2_2,x,app.load_RH(m));
                addpoints(app.h3_1,x,app.flexEx_LH(m+100));
                addpoints(app.h3_2,x,app.flexEx_RH(m));  
                
                % update 3d-model
                % update pelvis vertical axis              
                app.pel_Rot = quat2rotm(app.q_pelvis(app.tIndex,:));
                app.leftthigh_Rot = quat2rotm(app.q_leftthigh(app.tIndex,:));
                app.rightthigh_Rot =  quat2rotm(app.q_rightthigh(app.tIndex,:));
                
                app.P = (app.pel_Rot*[0 0 0.5]')';
                app.PV = (app.pel_Rot*[0 0.5 0]')';
                app.LT = (app.leftthigh_Rot* [0 -1 0]')';
                app.RT = (app.rightthigh_Rot* [0 -1 0]')';
               
                app.h4_1.XData = [0 app.PV(1)];
                app.h4_1.YData = [0 app.PV(3)];
                app.h4_1.ZData = [0 app.PV(2)];

                % update pelvis
                app.h4_2.XData = [-app.P(1) app.P(1)];
                app.h4_2.YData = [-app.P(3) app.P(3)];
                app.h4_2.ZData = [-app.P(2) app.P(2)];
            
                % update left leg
                app.h4_3.XData = [-app.P(1) -app.P(1)+app.LT(1)];
                app.h4_3.YData = [-app.P(3) -app.P(3)+app.LT(3)];
                app.h4_3.ZData = [-app.P(2) -app.P(2)+app.LT(2)];
           
                % update right thigh
                app.h4_4.XData = [app.P(1) app.P(1)+app.RT(1)];
                app.h4_4.YData = [app.P(3) app.P(3)+app.RT(3)];
                app.h4_4.ZData = [app.P(2) app.P(2)+app.RT(2)];
                
                % update title
                app.ModelPlot.Title.String = sprintf('Time (s):  %.1f',(app.tIndex-1)*0.002);                        
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: OpenFileButton
        function OpenFileButtonPushed(app, event)
            
            % get data file
            [fileName,path] = uigetfile('*.mat');
            load(fullfile(path,fileName));

            % Set time array
            app.time = 1:length(flexExLH);
            app.fs = 500;
            app.tIndex = 1;
            app.StartStop = 0;
            app.fps = 20;
            app.n = round(app.fs/app.fps);
       
            app.calib_status = 0;
            app.ind_finish_calib = ind_calib_finished;
            app.ind_after_calib = 0;
           
            % Initialize OPRIENTATION
            app.q_pelvis = quat_pelvis;
            app.q_leftthigh = quat_left;
            app.q_rightthigh = quat_right;
            
           
            % Joint loads
            app.flexEx_LH = flexExLH;             
            app.flexEx_RH = flexExRH;             
            app.load_LH = lhload ; 
            app.load_RH = lhload ; 
            
            % bar plot
            app.label = categorical({'low','jog','sprint','jump','kick'});
            app.label = reordercats(app.label,{'low','jog','sprint','jump','kick'});
            yyaxis(app.BoxPlot,'left');
            app.h1_1 = bar(app.BoxPlot,app.label,[0,0,0,0,0]);  
            app.BoxPlot.YLim =[0 round(max(app.time)/app.fs)];
            app.BoxPlot.YLabel.String = 'Time (s)';
            yyaxis(app.BoxPlot,'right');
            app.h1_2 = bar(app.BoxPlot,app.label,[0,0,0,0,0]);
            app.BoxPlot.YLabel.String = 'Number ';
            app.BoxPlot.YLim =[0 20];
            app.BoxPlot.Title.String = 'Activity Statistics';
            app.act_label= output_label;
            app.prev_act = 0;
            app.cur_act = 0;
            
            %top plot
            app.h2_1 = animatedline(app.topPlot,"Color",'#D95319'); 
            app.h2_2 = animatedline(app.topPlot,"Color",'#EDB120'); 
            app.h2_2.Visible ='off';
            app.topPlot.Title.String = 'Hip Load';
            app.topPlot.XLim = [0 round(max(app.time)/app.fs)];
            app.topPlot.XLabel.String = 'Time (s)';
            app.topPlot.YLim = [0 30];
            app.topPlot.YTick = 0:2:30;	
            
            % bottom plot
            app.h3_1 = animatedline(app.bottomPlot,"Color",'g'); 
            app.h3_2 = animatedline(app.bottomPlot,"Color",'b'); 
            app.h3_2.Visible ='off';
            app.bottomPlot.Title.String = 'Hip Angle';
            app.bottomPlot.XLim = [0 round(max(app.time)/app.fs)];
            app.bottomPlot.YLim = [0 360];
            app.bottomPlot.YTick = 0:20:360;
            app.bottomPlot.YTickLabel = num2cell(0:20:360);
            app.bottomPlot.YLabel.String = 'Deg';
            app.bottomPlot.XLabel.String = 'Time (s)';
            
            % bottom button
            app.dataBottom.Value = 'Left Hip';

            % coordinates for model
            app.P = zeros(1,3); 
            app.PV = zeros(1,3); 
            app.LT = zeros(1,3); 
            app.RT = zeros(1,3); 
            app.pel_Rot = zeros(3,3);
            app.leftthigh_Rot = zeros(3,3);
            app.rightthigh_Rot = zeros(3,3);
            
            % Initial model plot --> switch Y and Z around to show as ISB convention axes
            app.h4_1 = plot3(app.ModelPlot,[0 0],[0 0],[0 0.5],'r','LineWidth',3);
            hold(app.ModelPlot,'on');                                                                      % vertical pelvis
            app.h4_2 = plot3(app.ModelPlot,[0 0],[-0.5 0.5],[0 0],'r','LineWidth',3);                        % pelvis
            app.h4_3 = plot3(app.ModelPlot,[0 0],[0.5 0.5],[0 -1],'Color',[0 0.5 0],'LineWidth',3);          % left thigh
            app.h4_4 = plot3(app.ModelPlot,[0 0],[-0.5 -0.5],[0 -1],'b','LineWidth',3);                      %right thigh
            hold(app.ModelPlot,'off');
            app.ModelPlot.Title.String = ['Time (s):   ' num2str(0,'%.3f')];
            
        end

        % Value changed function: PausePlay
        function PausePlayValueChanged(app, event)
            %disp('pushed');
            if app.StartStop == 1
                app.StartStop = 0;
            elseif app.StartStop == 0
                app.StartStop = 1;
            end
            plotApp(app)
        end

        % Value changed function: dataBottom
        function dataBottomValueChanged(app, event)
                % plot title change
                if strcmp(app.dataBottom.Value,'Left Hip')
                    % change data
                    app.h2_1.Visible = 'on';
                    app.h3_1.Visible = 'on';
                    app.h2_2.Visible = 'off';
                    app.h3_2.Visible = 'off';
                    % change title and color
                    app.bottomPlot.Title.String = 'Left Hip Angle: Flexion(+)/Extension(-)';
                    app.topPlot.Title.String = 'Left Hip Load';
                    
                elseif strcmp(app.dataBottom.Value,'Right Hip')
                    app.h2_1.Visible = 'off';
                    app.h3_1.Visible = 'off'; 
                    app.h2_2.Visible = 'on';
                    app.h3_2.Visible = 'on'; 
                    app.bottomPlot.Title.String = 'Right Hip Angle: Flexion(+)/Extension(-)';
                    app.topPlot.Title.String = 'Right Hip Load';
                end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.topPlot = uiaxes(app.UIFigure);
            title(app.topPlot, 'Title')
            app.bottomPlot = uiaxes(app.UIFigure);
            title(app.bottomPlot, 'Title')
            app.BoxPlot = uiaxes(app.UIFigure);
            title(app.BoxPlot, 'Title')
            xlabel(app.BoxPlot, 'X')
            ylabel(app.BoxPlot, 'Y')
            zlabel(app.BoxPlot, 'Z')
            app.ModelPlot = uiaxes(app.UIFigure);
            title(app.ModelPlot, 'Title')
            xlabel(app.ModelPlot, 'X')
            ylabel(app.ModelPlot, 'Z')
            app.ModelPlot.XLim = [-2.5 2.5];
            app.ModelPlot.YLim = [-2.5 2.5];
            app.ModelPlot.ZLim = [-2.5 1];
            app.ModelPlot.YDir = 'reverse';
            app.PausePlay = uibutton(app.UIFigure, 'state');
            app.PausePlay.ValueChangedFcn = createCallbackFcn(app, @PausePlayValueChanged, true);
            app.PausePlay.Text = 'Pause/Play';
            app.OpenFileButton = uibutton(app.UIFigure, 'push');
            app.OpenFileButton.ButtonPushedFcn = createCallbackFcn(app, @OpenFileButtonPushed, true);
            app.OpenFileButton.Text = 'Open File';
            app.DisplayedDataLabel = uilabel(app.UIFigure);
            app.DisplayedDataLabel.Text = 'Displayed Data:';
            app.dataBottom = uidropdown(app.UIFigure);
            app.dataBottom.Items = {'Left Hip', 'Right Hip'};
            app.dataBottom.ValueChangedFcn = createCallbackFcn(app, @dataBottomValueChanged, true);
            app.dataBottom.Value = 'Left Hip';
            app.CalibrationStatusLabel = uilabel(app.UIFigure);
            app.CalibrationStatusLabel.Text = 'Calibration Status:';
            app.CalibrationStatusTextArea = uitextarea(app.UIFigure);
            app.CalibrationStatusTextArea.Value = {'Undone'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = UI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end